classdef TrussAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ASEN2001TrussAnalysisUIFigure  matlab.ui.Figure
        LoadTrussFileButton            matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        SaveAsButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        JointEditorLabel               matlab.ui.control.Label
        ConnectionEditorLabel          matlab.ui.control.Label
        Joints_UITable                 matlab.ui.control.Table
        Joints_MoveDownButton          matlab.ui.control.Button
        RemoveJointButton              matlab.ui.control.Button
        Bars_UITable                   matlab.ui.control.Table
        Joints_MoveUpButton            matlab.ui.control.Button
        CreateJointButton              matlab.ui.control.Button
        Bars_MoveDownButton            matlab.ui.control.Button
        RemoveBarButton                matlab.ui.control.Button
        Bars_MoveUpButton              matlab.ui.control.Button
        MainLabel                      matlab.ui.control.Label
        CreateBarButton                matlab.ui.control.Button
        BarLabel                       matlab.ui.control.Label
        JointLabel                     matlab.ui.control.Label
        PlotLabel                      matlab.ui.control.Label
        SupportEditorLabel             matlab.ui.control.Label
        Support_UITable                matlab.ui.control.Table
        Support_MoveDownButton         matlab.ui.control.Button
        RemoveSupportButton            matlab.ui.control.Button
        Support_MoveUpButton           matlab.ui.control.Button
        CreateSupportButton            matlab.ui.control.Button
        VerifyButton                   matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        CreateNewButton                matlab.ui.control.Button
        AutosaveCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        truss           % structure containing truss data inputs (joints, connectivity, supports, and external loads)
        loadFileName    % string containing current file name
        loadFilePath    % string containing current file path
        jointIDselect   % current row selection on the Joint Editor table
        barIDselect     % current row selection on the Connection Editor table
        supportIDselect % current row selection on the Support Editor table
    end
    
    methods (Access = private)
        
        
        function TrussPlotter(app)
            cla(app.UIAxes)
            joints = app.truss.joints;
            bars   = app.truss.bars;
            reacts = app.truss.reacts;
            
            rj = reacts(:,1);
            
            res  = 50;  % resolution of surfaces
            radj = 0.5; % radius of joints
            radc = 0.2; % radius of bars
            
            app.PlotLabel.Text = '';
            
            % Plot bars
            for i = 1:size(bars,1) % for every bar
                try
                    b1 = bars(i,1); % get bar connections
                    b2 = bars(i,2);
                    
                    j1 = joints(b1,:); % get coordinates of connected joints
                    j2 = joints(b2,:);
                    
                    [sx, sy, sz] = cylinder(radc,res); % create cylinder surface
                    sz = sz*norm(j2-j1); % scale the length
                    
                    vrr = vrrotvec(j2-j1,[0;0;1]); % find Euler axis-angle pair
                    if vrr(4) == 0 % convert to quaternion identity
                        quat = dcm2quat(eye(3));
                    else % convert to quaternion
                        quat = sin(vrr(4)/2)*[cot(vrr(4)/2), vrr(1), vrr(2), vrr(3)];
                    end
                    rv1 = quatrotate(quat,[sx(1,:); sy(1,:); sz(1,:)]');
                    rv2 = quatrotate(quat,[sx(2,:); sy(2,:); sz(2,:)]');
                    
                    sx = [rv1(:,1)'; rv2(:,1)'] + j1(1);
                    sy = [rv1(:,2)'; rv2(:,2)'] + j1(2);
                    sz = [rv1(:,3)'; rv2(:,3)'] + j1(3);
                    
                    if app.barIDselect == i
                        c = [0         0.6353    0.9098]; % blue
                    else
                        c = [0.9290    0.6940    0.1250]; % yellow
                    end
                    b(i) = surf(app.UIAxes,sx,sy,sz,'EdgeColor','none','FaceColor',c);
                catch
                    app.PlotLabel.Text = 'One or more bars could not be plotted correctly.';
                end
            end
            
            
            % Plot joints
            for i = 1:size(joints,1) % for every joint
                c = 'k'; % by default, all joints are black
                if sum(rj == i) % if the joint has a reaction load, plot it red
                    c = 'r';
                end
                if app.jointIDselect == i % if the joint is selected
                    c = [0.2471    0.2824    0.8000]; % dark blue
                end
                [sx, sy, sz] = sphere(res); % create sphere surface
                sx = sx*radj + joints(i,1); % scale sphere to size of joints
                sy = sy*radj + joints(i,2); % move points to joint position
                sz = sz*radj + joints(i,3);
                j(i) = surf(app.UIAxes,sx,sy,sz,'EdgeColor','none','FaceColor',c);
            end
            
            rotate3d(app.UIAxes,'on');
            daspect(app.UIAxes,[1 1 1]);
            
            app.UIAxes.XLabel.String = 'X';
            app.UIAxes.YLabel.String = 'Y';
            app.UIAxes.ZLabel.String = 'Z';
        end
        
        function T = ReadInputFile(app, fname)
            % "block" sets the kind of input argument that is meant to be read.
            % 0 - initializer line
            % 1 - joint block
            % 2 - connectivity block
            % 3 - reaction forces
            % 4 - external loads
            
            block = 0;
            count = 0;
            
            fid = fopen(fname);
            frewind(fid);
            
            line = fgetl(fid);
            % Each time fgetl(fid) is called, the next line is read and the output
            % contains the string of that line. If there are no more lines to read, the
            % output is -1.
            while line ~= -1
                % If the current line is commented out using a '#' symbol as the first
                % character or is empty, then move to the next line and begin from the
                % start of the while loop.
                if line(1) == '#'
                    line = fgetl(fid);
                    continue;
                end
                
                % Otherwise, read the necessary input data and save it to the truss
                % structure.
                switch block
                    case 0
                        % Get number of joints, bars, reactions, and external loads
                        % from the first data line in the file.
                        dims = sscanf(line,'%d%d%d%d%d');
                        
                        % Initiailize output matrices.
                        numJoints = dims(1);
                        joints    = zeros(numJoints,3);
                        numBars   = dims(2);
                        bars      = zeros(numBars,2);
                        numReacts = dims(3);
                        reacts    = zeros(numReacts,4);
                        numLoads  = dims(4);
                        loads     = zeros(numLoads,4);
                        
                        % Change to the next command block.
                        block = block + 1;
                        
                    case 1
                        % Get the coordinates of the truss joints.
                        dat    = sscanf(line,'%d%e%e%e');
                        jid    = dat(1);   % joint id
                        jcoord = dat(2:4); % cooordinate vector
                        
                        joints(jid,:) = jcoord;
                        count = count + 1;
                        
                        % If the block is complete, proceed to the next one.
                        if count == numJoints
                            count = 0;
                            block = block + 1;
                        end
                        
                    case 2
                        dat = sscanf(line,'%d%d%d%d%d');
                        bid = dat(1); % bar id
                        b1  = dat(2); % bar 1
                        b2  = dat(3); % bar 2
                        
                        bars(bid,:) = [b1; b2];
                        count = count + 1;
                        
                        % If the block is complete, proceed to the next one.
                        if count == numBars
                            count = 0;
                            block = block + 1;
                        end
                        
                    case 3
                        dat  = sscanf(line,'%d%e%e%e%e');
                        rid  = dat(1);   % reaction joint id
                        rdir = dat(2:4); % direction of reaction
                        % Convert reaction direction vetor to unit vector.
                        rdir = rdir/norm(rdir);
                        
                        reacts(count+1,:) = [rid; rdir];
                        count = count + 1;
                        
                        % If the block is complete, proceed to the next one.
                        if count == numReacts
                            count = 0;
                            block = block + 1;
                        end
                        
                    case 4
                        dat  = sscanf(line,'%d%e%e%e%e');
                        lid  = dat(1);   % external load joint id
                        lvec = dat(2:4); % external load vector
                        
                        reacts(count+1,:) = [lid; lvec];
                        count = count + 1;
                        
                        % If the block is complete, proceed to the next one.
                        if count == numLoads
                            count = 0;
                            block = block + 1;
                        end
                end
                
                % Continue to the next line and repeat.
                line = fgetl(fid);
            end
            
            % Save output matrices into output structure.
            T.joints = joints;
            T.bars   = bars;
            T.reacts = reacts;
            T.loads  = loads;
            
            % Close the input file.
            fclose(fid);
        end
        
        function UpdateBuild(app)
            % The pauses and drawnows are used to reduce the number of times MATLAB gets hung up on updating tables.
            % This inconsistent bug causes the app to lock up and force MATLAB to be closed. Save often!
            % Update tables with most recent data
            pause(0.01); drawnow;
            app.Joints_UITable.Data = [(1:size(app.truss.joints,1))', app.truss.joints];
            pause(0.01); drawnow;
            app.Bars_UITable.Data = [(1:size(app.truss.bars,1))', app.truss.bars];
            pause(0.01); drawnow;
            app.Support_UITable.Data = app.truss.reacts;
            pause(0.01); drawnow;
            % Update truss preview plot
            TrussPlotter(app);
            pause(0.01); drawnow;
            
            % If autosave is enabled, save the truss.
            if (app.AutosaveCheckBox.Value)
                WriteOutputFile(app, app.loadFileName, app.loadFilePath);
            end
        end
        
        function WriteOutputFile(app, writeName, writePath)
            % Creates a new file, overwriting the old one if it exists with the same name, and prints results of the current truss
            % structure in the proper format.
            
            % Get preliminary information regarding the number of components
            numJoints = size(app.truss.joints,1);
            numBars   = size(app.truss.bars,1);
            numReacts = size(app.truss.reacts,1);
            numLoads  = size(app.truss.loads,1);
            
            % Create the file
            fid = fopen([writePath, writeName], 'wt'); % Opens a new file, overwriting the old one if it already exists
            
            % Write output in an acceptable format
            fprintf(fid, '# Truss Input File\n#\n# Number of joints, bar, reactions, and external loads\n');
            % Print preliminary sizing line
            fprintf(fid, '%d  %d  %d  %d\n', numJoints, numBars, numReacts, numLoads);
            fprintf(fid, '#\n# Magnet joints and their coordinates\n');
            for i = 1:numJoints % Print joint coordinates
                fprintf(fid, '%.3d  %8.3f  %8.3f  %8.3f\n', i, app.truss.joints(i,1), app.truss.joints(i,2), app.truss.joints(i,3));
            end
            fprintf(fid, '#\n# Rigid rods and their associated joints\n');
            for i = 1:numBars % Print rod connections
                fprintf(fid, '%.3d  %.3d  %.3d\n', i, app.truss.bars(i,1), app.truss.bars(i,2));
            end
            fprintf(fid, '#\n# Reaction Forces\n');
            for i = 1:numReacts % Print reaction supports
                fprintf(fid, '%.3d %8.3f  %8.3f  %8.3f\n', app.truss.reacts(i,:));
            end
            fprintf(fid, '#\n# External Loads\n');
            for i = 1:numLoads % Print external loads
                fprintf(fid, '%.3d %8.3f  %8.3f  %8.3f\n', app.truss.loads(i,:));
            end
            fprintf(fid, '# End.');
            fclose(fid);
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainLabel.Text = 'Select a premade truss file or create a new truss.';
            app.BarLabel.Text = '';
            app.JointLabel.Text = '';
        end

        % Button pushed function: LoadTrussFileButton
        function LoadTrussFileButtonPushed(app, event)
            confirm = questdlg('Loading a new truss will overwrite any current design. Are you sure you wish to continue?', ...
                'Load Truss','Yes','No','No');
            if numel(confirm) == 3
                [app.loadFileName, app.loadFilePath] = uigetfile('*.*');
                if ~isempty(app.loadFileName) % If a file was actually selected
                [fid,errmsg] = fopen([app.loadFilePath app.loadFileName], 'a');
                if ~isempty(errmsg)&&strcmp(errmsg,'Permission denied')
                    app.MainLabel.Text = ['Error: You do not have permission to the folder: ', fileName, '.'];
                else
                    app.MainLabel.Text =  'File successfully opened.';
                end
                fclose(fid);
                
                % app.truss = TrussReadInput([app.loadFilePath app.loadFileName]);
                app.truss = ReadInputFile(app, [app.loadFilePath app.loadFileName]);
                app.MainLabel.Text = 'Truss inputs successfully retrieved.';
                
                % Prevent GUI from being hidden behind main MATLAB window
                app.ASEN2001TrussAnalysisUIFigure.Visible = 'off';
                app.ASEN2001TrussAnalysisUIFigure.Visible = 'on';
                
                UpdateBuild(app);
                end
            end
        end

        % Cell selection callback: Joints_UITable
        function Joints_UITableCellSelection(app, event)
            app.jointIDselect = event.Indices(1);
            TrussPlotter(app);
        end

        % Cell selection callback: Bars_UITable
        function Bars_UITableCellSelection(app, event)
            app.barIDselect = event.Indices(1);
            drawnow; pause(0.01);
            TrussPlotter(app);
            drawnow; pause(0.01);
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            [writeName, writePath] = uiputfile('.inp','Save As');
            if writeName ~= 0 % If a file was actually selected
                % Try opening file to determine write access
                [fid,errmsg] = fopen([writePath, writeName], 'wt');
                if ~isempty(errmsg)&&strcmp(errmsg,'Permission denied') % If the user does not have write access
                    app.MainLabel.Text = ['Error: You do not have permission to the folder: ', fileName, '.'];
                else % If the user has proper access to the selected directory
                    fclose(fid);
                    % Write file to the specified directory
                    WriteOutputFile(app, writeName, writePath);
                    app.MainLabel.Text = ['File saved as ', writePath, writeName];
                    % Update current file information for future saves
                    app.loadFileName = writeName;
                    app.loadFilePath = writePath;
                end
            end
            % Prevent GUI from being hidden behind main MATLAB window
            app.ASEN2001TrussAnalysisUIFigure.Visible = 'off';
            app.ASEN2001TrussAnalysisUIFigure.Visible = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        % The primary save button overwrites the currently loaded file with the updated truss data structure information. Because this overwrites the old file,
        % the user must confirm the option after pushing the button.
            confirm = questdlg('Saving this truss will overwrite the currently loaded file. Are you sure you want to save?', ...
                'Save As', 'Yes', 'No', 'Cancel');
            switch confirm
                case 'Yes' % Other selections or unexpected closing of the dialog box do not need to return an output
                    WriteOutputFile(app, app.loadFileName, app.loadFilePath);
                    app.MainLabel.Text = ['File saved as ', app.loadFilePath, app.loadFileName];
            end
        end

        % Button pushed function: Joints_MoveUpButton
        function Joints_MoveUpButtonPushed(app, event)
        % This function re-arranges the joint indices by taking the currently selected row in the joint table and moving it up by one index. This button does
        % nothing if the joint index is already 1. Due to MATLAB limitations (as of R018a), multiple selections are not considered valid, and only the lowest
        % index joint will be moved.
            jID = app.jointIDselect;
            if jID ~= 1 && ~isempty(jID) && size(app.truss.joints,1)>1 % If the joint isn't already the first one and if there are at least 2 joints
                % Switch rows in the joints matrix
                tmp = app.truss.joints;
                tmp(jID-1,:) = app.truss.joints(jID,:);
                tmp(jID,:) = app.truss.joints(jID-1,:);
                app.truss.joints = tmp;
                
                % Adjust bar connectivity in the bars matrix
                tmp = app.truss.bars;
                tmp(app.truss.bars == jID) = jID - 1;
                tmp(app.truss.bars == jID - 1) = jID;
                app.truss.bars = tmp;
                
                % Adjust reaction load joint IDs
                tmp = app.truss.reacts;
                tmp(app.truss.reacts(:,1) == jID) = jID - 1;
                tmp(app.truss.reacts(:,1) == jID - 1) = jID;
                app.truss.reacts = tmp;
                
                % Adjust external load joint IDs
                tmp = app.truss.loads;
                tmp(app.truss.loads(:,1) == jID) = jID - 1;
                tmp(app.truss.loads(:,1) == jID - 1) = jID;
                app.truss.loads = tmp;
                
                % Update
                UpdateBuild(app);
            end
        end

        % Button pushed function: Joints_MoveDownButton
        function Joints_MoveDownButtonPushed(app, event)
        % This function re-arranges the joint indices by taking the currently selected row in the joint table and moving it down by one index. This button does
        % nothing if the joint index is already the highest. Due to MATLAB limitations (as of R018a), multiple selections are not considered valid, and only the
        % lowest indexed joint will be moved.
            jID = app.jointIDselect;
            if jID ~= size(app.truss.joints,1) && ~isempty(jID) % If the joint isn't already the last one
                % Switch rows in the joints matrix
                tmp = app.truss.joints;
                tmp(jID + 1,:) = app.truss.joints(jID,:);
                tmp(jID,:) = app.truss.joints(jID + 1,:);
                app.truss.joints = tmp;
                
                % Adjust bar connectivity in the bars matrix
                tmp = app.truss.bars;
                tmp(app.truss.bars == jID) = jID + 1;
                tmp(app.truss.bars == jID + 1) = jID;
                app.truss.bars = tmp;
                
                % Adjust reaction load joint IDs
                tmp = app.truss.reacts;
                tmp(app.truss.reacts(:,1) == jID) = jID + 1;
                tmp(app.truss.reacts(:,1) == jID + 1) = jID;
                app.truss.reacts = tmp;
                
                % Adjust external load joint IDs
                tmp = app.truss.loads;
                tmp(app.truss.loads(:,1) == jID) = jID + 1;
                tmp(app.truss.loads(:,1) == jID + 1) = jID;
                app.truss.loads = tmp;
                
                % Update
                UpdateBuild(app);
            end
        end

        % Button pushed function: RemoveBarButton
        function RemoveBarButtonPushed(app, event)
            if ~isempty(app.barIDselect) && app.barIDselect <= size(app.truss.bars,1)
                % Remove the bar from the truss structure matrix
                fprintf('Remove bar button pushed. Executing callback.\n');
                app.truss.bars(app.barIDselect,:) = [];
                
                fprintf('Truss bar matrix updated to remove most recent line.\n');
                % Update
                
                fprintf('Running UpdateBuild(app) function.\n');
                UpdateBuild(app);
            end
        end

        % Button pushed function: CreateBarButton
        function CreateBarButtonPushed(app, event)
            app.truss.bars = [app.truss.bars; 0, 0]; % Append a new row to the bars matrix
            drawnow; pause(0.01);
            
            % Update
            UpdateBuild(app);
        end

        % Cell edit callback: Bars_UITable
        function Bars_UITableCellEdit(app, event)
            % Changes to the Connection Editor table force an update to the truss to reflect the changes. The changed input must be
            % a valid joint ID number.
            if mod(event.NewData,1) == 0 && event.NewData > 0 && event.NewData <= size(app.truss.joints,1)
                app.truss.bars = app.Bars_UITable.Data(:,2:3); % Update the truss structure
                app.BarLabel.Text = '';
                
            else
                app.BarLabel.Text = 'Input must be a valid joint ID.';
            end
            drawnow; pause(0.01);
            UpdateBuild(app);
        end

        % Button pushed function: Bars_MoveUpButton
        function Bars_MoveUpButtonPushed(app, event)
        % This function allows the user to reorder the indexing of the bars. Functionality is similar to MoveUpButtonPushed()
            bID = app.barIDselect;
            if bID ~= 1 && ~isempty(bID) % If the bar isn't already the first one
                % Switch rows in the bars matrix
                tmp = app.truss.bars;
                tmp(bID-1,:) = app.truss.bars(bID,:);
                tmp(bID,:) = app.truss.bars(bID-1,:);
                app.truss.bars = tmp;
            end
            UpdateBuild(app);
        end

        % Button pushed function: Bars_MoveDownButton
        function Bars_MoveDownButtonPushed(app, event)
        % See other MoveUp/DownButtonPushed functions. This button works with the bar connectivity table and allows the user to reindex connections.
            bID = app.barIDselect;
            if bID ~= size(app.truss.bars,1) && ~isempty(bID) % If the bar isn't already the last one
                % Switch rows in the bars matrix
                tmp = app.truss.bars;
                tmp(bID+1,:) = app.truss.bars(bID,:);
                tmp(bID,:) = app.truss.bars(bID+1,:);
                app.truss.bars = tmp;
            end
            UpdateBuild(app);
        end

        % Button pushed function: CreateJointButton
        function CreateJointButtonPushed(app, event)
            app.truss.joints = [app.truss.joints; 0, 0, 0]; % Append a new row to the truss' joints matrix
            
            % Update
            UpdateBuild(app);
        end

        % Cell edit callback: Joints_UITable
        function Joints_UITableCellEdit(app, event)
            % Changes to the Connection Editor table force an update to the truss to reflect the changes. The changed input must be
            % a valid double type number.
            if isa(event.NewData,'double')
                app.truss.joints = app.Joints_UITable.Data(:,2:4); % Update the truss structure with new data
                app.JointLabel.Text = '';
            else
                app.JointLabel.Text = 'Input must be a number.';
            end
            drawnow; pause(0.01);
            UpdateBuild(app);
        end

        % Cell edit callback: Support_UITable
        function Support_UITableCellEdit(app, event)
            if event.Indices(2) == 1 % If the joint ID was changed, input must be a valid joint ID
                if mod(event.NewData,1) == 0 && event.NewData > 0 && event.NewData <= size(app.truss.joints,1)
                    app.truss.reacts = app.Support_UITable.Data; % Update the truss structure
                end
            else % If the support direction was changed, input must be a valid number
                if isa(event.NewData,'double')
                    app.truss.reacts = app.Support_UITable.Data; % Update the truss structure with new data
                end
            end
            drawnow; pause(0.01);
            UpdateBuild(app);
        end

        % Cell selection callback: Support_UITable
        function Support_UITableCellSelection(app, event)
            app.supportIDselect = event.Indices(1);
        end

        % Button pushed function: Support_MoveUpButton
        function Support_MoveUpButtonPushed(app, event)
            % This function allows the user to reorder the indexing of the support reactions.
            sID = app.supportIDselect;
            if sID ~= 1 && ~isempty(sID) % If the bar isn't already the first one
                % Switch rows in the bars matrix
                tmp = app.truss.reacts;
                tmp(sID-1,:) = app.truss.reacts(sID,:);
                tmp(sID,:) = app.truss.reacts(sID-1,:);
                app.truss.reacts = tmp;
                
                UpdateBuild(app);
            end
        end

        % Button pushed function: Support_MoveDownButton
        function Support_MoveDownButtonPushed(app, event)
        % See other MoveUp/DownButtonPushed functions. This button works with the support table and allows the user to reindex support reactions.
            sID = app.supportIDselect;
            if sID ~= size(app.truss.reacts,1) && ~isempty(sID) % If the support isn't already the last one
                % Switch rows in the bars matrix
                tmp = app.truss.reacts;
                tmp(sID+1,:) = app.truss.reacts(sID,:);
                tmp(sID,:) = app.truss.reacts(sID+1,:);
                app.truss.reacts = tmp;
                
                UpdateBuild(app);
            end
        end

        % Button pushed function: RemoveJointButton
        function RemoveJointButtonPushed(app, event)
            jID = app.jointIDselect; % currently selected joint
            if ~isempty(jID) && jID <= size(app.truss.joints,1)
                app.truss.bars(max(app.truss.bars' == jID), :) = []; % remove all associated bars
                app.truss.bars(app.truss.bars >= jID) = app.truss.bars(app.truss.bars >= jID) - 1; % re-index bars that are affected
                app.truss.reacts(app.truss.reacts(:,1) == jID, :) = []; % remove all associated support reactions
                app.truss.reacts(app.truss.reacts >= jID) = app.truss.reacts(app.truss.reacts >= jID) - 1; % re-index bars that are affected
                app.truss.loads(app.truss.loads(:,1) == jID, :) = []; % remove all associated external loads
                app.truss.loads(app.truss.loads >= jID) = app.truss.loads(app.truss.loads >= jID) - 1; % re-index external loads that are affected
                app.truss.joints(jID,:) = []; % remove joint
                
                UpdateBuild(app);
            end
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            
            % Check for the most basic statically determinate condition
            nLHS = 3*size(app.truss.joints,1);
            nRHS = size(app.truss.bars,1) + size(app.truss.reacts,1);
            if nLHS == nRHS
                s1 = '';
            else
                s1 = 'The space truss must satisfy the following condition: 3*joints = bars + supports. ';
            end
            
            % Verify that the correct number of support reactions are present
            if size(app.truss.reacts,1) == 6
                s2 = '';
            else
                s2 = 'Incorrect number of support reactions. Space trusses must have exactly 6 to be statically determinate. ';
            end
            
            % Verify that support reactions are unique
            s3 = '';
            s4 = '';
            for i = 1:size(app.truss.reacts,1)
                for j = i+1:size(app.truss.reacts,1)
                    if app.truss.reacts(i,:) == app.truss.reacts(j,:)
                        s3 = 'All support reactions must be unique. ';
                        s4 = [s4, 'Reactions ' num2str(i) ' and ' num2str(j) ' are identical. '];
                    end
                end
            end
            
            % Verify that all joints are unique
            s5 = '';
            s6 = '';
            for i = 1:size(app.truss.joints,1)-1
                for j = i+1:size(app.truss.joints,1)
                    if app.truss.joints(i,:) == app.truss.joints(j,:)
                        s5 = 'All joints must be unique. ';
                        s6 = [s6, 'Joints ' num2str(i) ' and ' num2str(j) ' are identical. '];
                    end
                end
            end
            
            % Verify that all bars have a valid joint ID input
            s7 = '';
            for i = 1:size(app.truss.bars,1)
                if app.truss.bars(i,1) == app.truss.bars(i,2)
                    s7 = [s7, 'Bar ' num2str(i) ' has connections on the same joint. '];
                end
                if app.truss.bars(i,1) < 1 || app.truss.bars(i,1) > size(app.truss.joints,1) || app.truss.bars(i,2) < 1 || app.truss.bars(i,2) > size(app.truss.joints,1)
                    s7 = [s7, 'Bar ' num2str(i) ' does not have valid joint ID inputs. '];
                end
            end
            
            % Verify that all bars are unique
            s8 = '';
            s9 = '';
            for i = 1:size(app.truss.bars,1)-1
                for j = i+1:size(app.truss.bars,1)
                    if app.truss.bars(i,:) == app.truss.bars(j,:)
                        s8 = 'All bars must be unique. ';
                        s9 = [s9, 'Bars ' num2str(i) ' and ' num2str(j) ' are identical. '];
                    end
                end
            end
            
            % Build the A matrix. Don't look here until you build the function on your own (:
            numjoints = size(app.truss.joints,1); numbars = size(app.truss.bars,1);
            numreact = size(app.truss.reacts,1); numeqns = 3 * numjoints;
            Amat = zeros(numeqns);
            for i=1:numjoints
                idx = 3*i-2; idy = 3*i-1; idz = 3*i;
                [ibar,ijt]=find(app.truss.bars==i);
                for ib=1:length(ibar)
                    barid=ibar(ib); joint_i = app.truss.joints(i,:);
                    if ijt(ib) == 1
                        jid = app.truss.bars(barid,2);
                    else
                        jid = app.truss.bars(barid,1);
                    end
                    joint_j = app.truss.joints(jid,:); vec_ij = joint_j - joint_i;
                    uvec   = vec_ij/norm(vec_ij); Amat([idx idy idz],barid)=uvec;
                end
            end
            for i=1:numreact
                jid=app.truss.reacts(i,1); idx = 3*jid-2; idy = 3*jid-1; idz = 3*jid;
                Amat([idx idy idz],numbars+i)=app.truss.reacts(i,2:4);
            end
            if rank(Amat) == numeqns
                s0 = 'Truss is statically determinate! ';
            else
                s0 = ['Truss is not statically determinate! ' num2str(rank(Amat)) ' < ' num2str(numeqns) ' '];
            end
            if ~isempty(s2)
                s0 = '';
            end
            
            app.TextArea.Value = [s0, s1, s2, s3, s4, s5, s6, s7, s8, s9];
            
        end

        % Button pushed function: RemoveSupportButton
        function RemoveSupportButtonPushed(app, event)
            app.truss.reacts(app.supportIDselect,:) = [];
            UpdateBuild(app);
        end

        % Button pushed function: CreateSupportButton
        function CreateSupportButtonPushed(app, event)
            app.truss.reacts = [app.truss.reacts; 0, 0, 0, 0];
            UpdateBuild(app);
        end

        % Button pushed function: CreateNewButton
        function CreateNewButtonPushed(app, event)
            confirm = questdlg('Creating a new truss will close the currently loaded file. Are you sure?', ...
                'Save As', 'Yes', 'No', 'No');
            switch confirm
                case 'Yes'
                    app.truss.joints = [0, 0, 0; 30, 0, 0; 0, 6, 0; 30, 6, 0];
                    drawnow; pause(0.01);
                    app.truss.reacts = [1, 0, 0, 1; 1, 0.5 0.5, 0; 2, 0, 0, 1; 3, 0, 0, 1; 3, 0.5, -0.5, 0; 4, 0, 0, 1];
                    drawnow; pause(0.01);
                    app.truss.bars   = [1, 2; 1, 3; 2, 4; 3, 4];
                    drawnow; pause(0.01);
                    app.truss.loads  = [1, 0, 0, 0];
                    SaveAsButtonPushed(app);
                    drawnow; pause(0.01);
                    UpdateBuild(app);
                    drawnow; pause(0.01);
            end
            % Prevent GUI from being hidden behind main MATLAB window
            app.ASEN2001TrussAnalysisUIFigure.Visible = 'off';
            app.ASEN2001TrussAnalysisUIFigure.Visible = 'on';
        end

        % Value changed function: AutosaveCheckBox
        function AutosaveCheckBoxValueChanged(app, event)
            value = app.AutosaveCheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ASEN2001TrussAnalysisUIFigure = uifigure;
            app.ASEN2001TrussAnalysisUIFigure.Name = 'ASEN 2001 Truss Analysis';
            app.LoadTrussFileButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.LoadTrussFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrussFileButtonPushed, true);
            app.LoadTrussFileButton.Text = 'Load Truss File';
            app.SaveButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveAsButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As...';
            app.UIAxes = uiaxes(app.ASEN2001TrussAnalysisUIFigure);
            title(app.UIAxes, 'Truss Preview')
            app.JointEditorLabel = uilabel(app.ASEN2001TrussAnalysisUIFigure);
            app.JointEditorLabel.Text = 'Joint Editor';
            app.ConnectionEditorLabel = uilabel(app.ASEN2001TrussAnalysisUIFigure);
            app.ConnectionEditorLabel.Text = 'Connection Editor';
            app.Joints_UITable = uitable(app.ASEN2001TrussAnalysisUIFigure);
            app.Joints_UITable.ColumnName = {'Joint ID'; 'X'; 'Y'; 'Z'};
            app.Joints_UITable.RowName = {};
            app.Joints_UITable.ColumnEditable = [false true true true];
            app.Joints_UITable.CellEditCallback = createCallbackFcn(app, @Joints_UITableCellEdit, true);
            app.Joints_UITable.CellSelectionCallback = createCallbackFcn(app, @Joints_UITableCellSelection, true);
            app.Joints_MoveDownButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.Joints_MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @Joints_MoveDownButtonPushed, true);
            app.Joints_MoveDownButton.Text = 'Move Down';
            app.RemoveJointButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.RemoveJointButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveJointButtonPushed, true);
            app.RemoveJointButton.Text = 'Remove Joint';
            app.Bars_UITable = uitable(app.ASEN2001TrussAnalysisUIFigure);
            app.Bars_UITable.ColumnName = {'Bar ID'; 'Joint 1 ID'; 'Joint 2 ID'};
            app.Bars_UITable.RowName = {};
            app.Bars_UITable.ColumnEditable = [false true true];
            app.Bars_UITable.CellEditCallback = createCallbackFcn(app, @Bars_UITableCellEdit, true);
            app.Bars_UITable.CellSelectionCallback = createCallbackFcn(app, @Bars_UITableCellSelection, true);
            app.Joints_MoveUpButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.Joints_MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @Joints_MoveUpButtonPushed, true);
            app.Joints_MoveUpButton.Text = 'Move Up';
            app.CreateJointButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.CreateJointButton.ButtonPushedFcn = createCallbackFcn(app, @CreateJointButtonPushed, true);
            app.CreateJointButton.Text = 'Create Joint';
            app.Bars_MoveDownButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.Bars_MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @Bars_MoveDownButtonPushed, true);
            app.Bars_MoveDownButton.Text = 'Move Down';
            app.RemoveBarButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.RemoveBarButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBarButtonPushed, true);
            app.RemoveBarButton.Text = 'Remove Bar';
            app.Bars_MoveUpButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.Bars_MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @Bars_MoveUpButtonPushed, true);
            app.Bars_MoveUpButton.Text = 'Move Up';
            app.MainLabel = uilabel(app.ASEN2001TrussAnalysisUIFigure);
            app.CreateBarButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.CreateBarButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBarButtonPushed, true);
            app.CreateBarButton.Text = 'Create Bar';
            app.BarLabel = uilabel(app.ASEN2001TrussAnalysisUIFigure);
            app.BarLabel.Text = 'BarLabel';
            app.JointLabel = uilabel(app.ASEN2001TrussAnalysisUIFigure);
            app.JointLabel.Text = 'JointLabel';
            app.PlotLabel = uilabel(app.ASEN2001TrussAnalysisUIFigure);
            app.PlotLabel.Text = 'PlotLabel';
            app.SupportEditorLabel = uilabel(app.ASEN2001TrussAnalysisUIFigure);
            app.SupportEditorLabel.Text = 'Support Editor';
            app.Support_UITable = uitable(app.ASEN2001TrussAnalysisUIFigure);
            app.Support_UITable.ColumnName = {'Joint ID'; 'X'; 'Y'; 'Z'};
            app.Support_UITable.RowName = {};
            app.Support_UITable.ColumnEditable = true;
            app.Support_UITable.CellEditCallback = createCallbackFcn(app, @Support_UITableCellEdit, true);
            app.Support_UITable.CellSelectionCallback = createCallbackFcn(app, @Support_UITableCellSelection, true);
            app.Support_MoveDownButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.Support_MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @Support_MoveDownButtonPushed, true);
            app.Support_MoveDownButton.Text = 'Move Down';
            app.RemoveSupportButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.RemoveSupportButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSupportButtonPushed, true);
            app.RemoveSupportButton.Text = 'Remove Support';
            app.Support_MoveUpButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.Support_MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @Support_MoveUpButtonPushed, true);
            app.Support_MoveUpButton.Text = 'Move Up';
            app.CreateSupportButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.CreateSupportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSupportButtonPushed, true);
            app.CreateSupportButton.Text = 'Create Support';
            app.VerifyButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.TextArea = uitextarea(app.ASEN2001TrussAnalysisUIFigure);
            app.CreateNewButton = uibutton(app.ASEN2001TrussAnalysisUIFigure, 'push');
            app.CreateNewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewButtonPushed, true);
            app.CreateNewButton.Text = 'Create New';
            app.AutosaveCheckBox = uicheckbox(app.ASEN2001TrussAnalysisUIFigure);
            app.AutosaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutosaveCheckBoxValueChanged, true);
            app.AutosaveCheckBox.Text = 'Autosave';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TrussAnalysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ASEN2001TrussAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ASEN2001TrussAnalysisUIFigure)
        end
    end
end